classdef ARDUINO_CBD_PCB_DT_MOD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainControlsTab_2               matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        ConnectionsControlsTab          matlab.ui.container.Tab
        ConnectionsPanel                matlab.ui.container.Panel
        ConnectButton                   matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        ArduinoBoardDropDownLabel       matlab.ui.control.Label
        ArduinoBoardDropDown            matlab.ui.control.DropDown
        ClearConnectionButton           matlab.ui.control.Button
        COMPORTDropDownLabel            matlab.ui.control.Label
        COMPORTDropDown                 matlab.ui.control.DropDown
        Lamp_CP                         matlab.ui.control.Lamp
        TabGroup4                       matlab.ui.container.TabGroup
        BreakdownTab                    matlab.ui.container.Tab
        TargetVoltageVEditFieldLabel    matlab.ui.control.Label
        TargetVoltageVEditField         matlab.ui.control.NumericEditField
        CutoffCurrentnAEditFieldLabel   matlab.ui.control.Label
        CutoffCurrentnAEditField        matlab.ui.control.NumericEditField
        LatencysEditFieldLabel          matlab.ui.control.Label
        LatencysEditField               matlab.ui.control.NumericEditField
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        TimeoutsEditField               matlab.ui.control.NumericEditField
        STARTButton                     matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        A_Lamp                          matlab.ui.control.Lamp
        SAVEDATAButton                  matlab.ui.control.Button
        SAVE_LAMPLamp                   matlab.ui.control.Lamp
        FBVDACEditFieldLabel            matlab.ui.control.Label
        FBVDACEditField                 matlab.ui.control.NumericEditField
        DualThresholdCheckBox           matlab.ui.control.CheckBox
        VFLabel                         matlab.ui.control.Label
        VFEditField                     matlab.ui.control.NumericEditField
        IFLabel                         matlab.ui.control.Label
        IFEditField                     matlab.ui.control.NumericEditField
        ChipNameEditFieldLabel          matlab.ui.control.Label
        ChipNameEditField               matlab.ui.control.EditField
        TargetTab                       matlab.ui.container.Tab
        CalculateButton                 matlab.ui.control.Button
        UpdateButton                    matlab.ui.control.Button
        LnmEditFieldLabel               matlab.ui.control.Label
        LnmEditField                    matlab.ui.control.NumericEditField
        dnmEditFieldLabel               matlab.ui.control.Label
        dnmEditField                    matlab.ui.control.NumericEditField
        KSmEditFieldLabel               matlab.ui.control.Label
        KSmEditField                    matlab.ui.control.NumericEditField
        VVEditFieldLabel                matlab.ui.control.Label
        VVEditField                     matlab.ui.control.NumericEditField
        InAEditFieldLabel               matlab.ui.control.Label
        InAEditField                    matlab.ui.control.NumericEditField
        ElectricFieldVnmEditFieldLabel  matlab.ui.control.Label
        ElectricFieldVnmEditField       matlab.ui.control.NumericEditField
        Lamp_3Label                     matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        IFractionEditFieldLabel         matlab.ui.control.Label
        IFractionEditField              matlab.ui.control.NumericEditField
        GrowthTab                       matlab.ui.container.Tab
        VLowVEditFieldLabel             matlab.ui.control.Label
        VLowVEditField                  matlab.ui.control.NumericEditField
        VHighVEditFieldLabel            matlab.ui.control.Label
        VHighVEditField                 matlab.ui.control.NumericEditField
        LowTimesEditFieldLabel          matlab.ui.control.Label
        LowTimesEditField               matlab.ui.control.NumericEditField
        HighTimesEditFieldLabel         matlab.ui.control.Label
        HighTimesEditField              matlab.ui.control.NumericEditField
        ENLARGEButton                   matlab.ui.control.Button
        TerminateButton                 matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        TargetInAEditFieldLabel         matlab.ui.control.Label
        TargetInAEditField              matlab.ui.control.NumericEditField
        MeanInAEditFieldLabel           matlab.ui.control.Label
        MeanInAEditField                matlab.ui.control.NumericEditField
        IMeasureDropDownLabel           matlab.ui.control.Label
        IMeasureDropDown                matlab.ui.control.DropDown
        IVTab                           matlab.ui.container.Tab
        StartingVVEditFieldLabel        matlab.ui.control.Label
        StartingVVEditField             matlab.ui.control.NumericEditField
        FinalVVEditFieldLabel           matlab.ui.control.Label
        FinalVVEditField                matlab.ui.control.NumericEditField
        StepsEditFieldLabel             matlab.ui.control.Label
        StepsEditField                  matlab.ui.control.NumericEditField
        GetIVButton                     matlab.ui.control.Button
        IV_Lamp                         matlab.ui.control.Lamp
        GSlopenSEditFieldLabel          matlab.ui.control.Label
        GSlopenSEditField               matlab.ui.control.NumericEditField
        ExportIVButton                  matlab.ui.control.Button
        IV_Export_Lamp                  matlab.ui.control.Lamp
        PorediameternmEditFieldLabel    matlab.ui.control.Label
        PorediameternmEditField         matlab.ui.control.EditField
        STOPIVButton                    matlab.ui.control.Button
        DiameterTab                     matlab.ui.container.Tab
        LnmEditField_2Label             matlab.ui.control.Label
        LnmEditField_2                  matlab.ui.control.NumericEditField
        KSmEditField_2Label             matlab.ui.control.Label
        KSmEditField_2                  matlab.ui.control.NumericEditField
        GnSEditField_2Label             matlab.ui.control.Label
        GnSEditField_2                  matlab.ui.control.NumericEditField
        dnmEditField_2Label             matlab.ui.control.Label
        dnmEditField_2                  matlab.ui.control.NumericEditField
        CalculateDiameterButton         matlab.ui.control.Button
        ResistorsTab                    matlab.ui.container.Tab
        ResistorsofOPAMPsPanel          matlab.ui.container.Panel
        RFeedbackMohmEditFieldLabel     matlab.ui.control.Label
        RFeedbackMohmEditField          matlab.ui.control.NumericEditField
        RInputMOhmEditFieldLabel        matlab.ui.control.Label
        RInputMOhmEditField             matlab.ui.control.NumericEditField
        RInputTIAMOhmEditFieldLabel     matlab.ui.control.Label
        RInputTIAMOhmEditField          matlab.ui.control.NumericEditField
        VDividerCurrentLabel            matlab.ui.control.Label
        VDividerCurrentEditField        matlab.ui.control.NumericEditField
        VDividerVoltageLabel            matlab.ui.control.Label
        VDividerVoltageEditField        matlab.ui.control.NumericEditField
        AddressesTab                    matlab.ui.container.Tab
        ADS1115AddressEditFieldLabel    matlab.ui.control.Label
        ADS1115AddressEditField         matlab.ui.control.EditField
        DACAddressEditFieldLabel        matlab.ui.control.Label
        DACAddressEditField             matlab.ui.control.EditField
        ThresholdsTab                   matlab.ui.container.Tab
        SecondaryVVEditFieldLabel       matlab.ui.control.Label
        SecondaryVVEditField            matlab.ui.control.NumericEditField
        SwitchtoSecondaryInAEditFieldLabel  matlab.ui.control.Label
        SwitchtoSecondaryInAEditField   matlab.ui.control.NumericEditField
        SecondaryICutoffEditFieldLabel  matlab.ui.control.Label
        SecondaryICutoffEditField       matlab.ui.control.NumericEditField
        TroubleshootingTab              matlab.ui.container.Tab
        ExpectedValuesPanel             matlab.ui.container.Panel
        OPAMP2mVEditFieldLabel          matlab.ui.control.Label
        OPAMP2mVEditField               matlab.ui.control.NumericEditField
        OPAMP3mVEditFieldLabel          matlab.ui.control.Label
        OPAMP3mVEditField               matlab.ui.control.NumericEditField
        DACmVEditFieldLabel             matlab.ui.control.Label
        DACmVEditField                  matlab.ui.control.NumericEditField
        AppliedVVEditFieldLabel         matlab.ui.control.Label
        AppliedVVEditField              matlab.ui.control.NumericEditField
        CalculateButton_2               matlab.ui.control.Button
        TestResistorMOhmEditFieldLabel  matlab.ui.control.Label
        TestResistorMOhmEditField       matlab.ui.control.NumericEditField
        ADS1115A0A1mVEditFieldLabel     matlab.ui.control.Label
        ADS1115A0A1mVEditField          matlab.ui.control.NumericEditField
        ADS1115A2A3mVEditFieldLabel     matlab.ui.control.Label
        ADS1115A2A3mVEditField          matlab.ui.control.NumericEditField
        NanoporeVEditFieldLabel         matlab.ui.control.Label
        NanoporeVEditField              matlab.ui.control.NumericEditField
        CurrentnAEditFieldLabel         matlab.ui.control.Label
        CurrentnAEditField              matlab.ui.control.NumericEditField
        TabGroup3                       matlab.ui.container.TabGroup
        CurrentTab                      matlab.ui.container.Tab
        StdnAEditFieldLabel             matlab.ui.control.Label
        StdnAEditField                  matlab.ui.control.NumericEditField
        RMSnAEditFieldLabel             matlab.ui.control.Label
        RMSnAEditField                  matlab.ui.control.NumericEditField
        RMSVEditFieldLabel              matlab.ui.control.Label
        RMSVEditField                   matlab.ui.control.NumericEditField
        StdmVEditFieldLabel             matlab.ui.control.Label
        StdmVEditField                  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        GnSEditFieldLabel               matlab.ui.control.Label
        GnSEditField                    matlab.ui.control.NumericEditField
        RMohmEditFieldLabel             matlab.ui.control.Label
        RMohmEditField                  matlab.ui.control.NumericEditField
        HelpTab                         matlab.ui.container.Tab
        HelpTextAreaLabel               matlab.ui.control.Label
        HelpTextArea                    matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        arduino_device
        ADS1115
        v_array
        t_array
        stop_CBD
        stop_enlarging
        stop_IV
        cutoff_I
        cutoff_current_nA
        cutoff_current_DT_nA
        amplification_ratio
        
        start_coversion_bit
        config_input_channels_0_1
        config_input_channels_2_3
        gain_bits
        operating_mode
        sample_rate
        bits_4_to_0
        upper_Full_Scale_Range
        
        DAC
        mean_I_array
        mean_V_array
        std_I_array
        std_V_array
        
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RFeedbackMohmEditField.Value=5;
            app.RInputMOhmEditField.Value=1;
            app.RInputTIAMOhmEditField.Value=10;%Transimpedence resistor
            app.stop_CBD=0;
            app.stop_IV=0;
            app.stop_enlarging=0;
            app.TimeoutsEditField.Value=1000;
            app.LatencysEditField.Value=5;
            app.CutoffCurrentnAEditField.Value=1500;
            app.cutoff_current_DT_nA=750;
            
            app.LnmEditField.Value=10;
            app.dnmEditField.Value=5;
            app.KSmEditField.Value=11.6;
            app.VVEditField.Value=5;
            
            app.TargetVoltageVEditField.Value=5;
            
            %Settingup the ADS-1115
            %page 28 of https://www.ti.com/lit/ds/symlink/ads1115.pdf?ts=1669272064121&ref_url=https%253A%252F%252Fwww.ti.com%252Fproduct%252FADS1115
            %https://au.mathworks.com/matlabcentral/answers/286348-how-i-can-read-a-signal-from-asd1115-with-arduino-and-matlab?s_tid=srchtitle
            app.start_coversion_bit='1';
            app.config_input_channels_0_1='000';
            app.config_input_channels_2_3='011';
            app.gain_bits='001';%+/-4.096
            app.operating_mode='1';%
            app.sample_rate='111';%860 sps
            app.bits_4_to_0='00011';

            app.upper_Full_Scale_Range=4.096;%governed by the gain bits (see above)
            
            %external DAC
            app.FBVDACEditField.Value=0.205;
            
            %rms and std
            app.RMSVEditField.Value=0;
            app.RMSnAEditField.Value=0;
            app.StdmVEditField.Value=0;
            app.StdnAEditField.Value=0;
            
            %Ratios
            app.VDividerCurrentEditField.Value=4;%*4 because of the voltage divider
            app.VDividerVoltageEditField.Value=1;%*no use of a voltage divider
            
            %Pore Enlarging
            app.VLowVEditField.Value=0.5;
            app.VHighVEditField.Value=5;
            app.LowTimesEditField.Value=5;
            app.HighTimesEditField.Value=2;
            
            app.MeanInAEditField.Value=0;
            app.TargetInAEditField.Value=0;
            
            %I-V
            app.StartingVVEditField.Value=0.2;
            app.FinalVVEditField.Value=1;
            app.StepsEditField.Value=10;
            
            %Estimated values
            app.AppliedVVEditField.Value=app.TargetVoltageVEditField.Value;
            app.TestResistorMOhmEditField.Value=10;
            
            %Chip Name
            app.ChipNameEditField.Value='Chip1';
            
            %Other
            app.IFractionEditField.Value=1;
        end

        % Drop down opening function: COMPORTDropDown
        function COMPORTDropDownOpening(app, event)
            %comport list
            %app.COMPORTDropDown=uidropdown(app.UIFigure);
            app.Lamp_CP.Color='yellow';
            drawnow;
            
            Serial_ports_from_dropdown=serialportlist("all");
            total_ports=length(Serial_ports_from_dropdown);
            for i=1:total_ports
                new_member=Serial_ports_from_dropdown(i);
                if ~any(ismember(app.COMPORTDropDown.Items, new_member))
                    app.COMPORTDropDown.Items = [app.COMPORTDropDown.Items new_member];
                end
            end
            
            app.Lamp_CP.Color='green';
            drawnow;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.Lamp.Color='yellow';
            drawnow;
            
            if strcmp(app.ArduinoBoardDropDown.Value,'Uno')==1
                board_type='Uno';
            end
            %app.arduino_device = arduino(app.COMPORTEditField.Value,board_type,'BaudRate',9600,'Libraries','I2C');
            app.arduino_device = arduino(app.COMPORTDropDown.Value,board_type,'BaudRate',9600,'Libraries','I2C');
            
            %Initializing the ADS1115
            app.ADS1115 = device(app.arduino_device,'I2CAddress',app.ADS1115AddressEditField.Value);
            
            %initializing the External DAC:  MCP4725
            app.DAC = device(app.arduino_device,'I2CAddress',app.DACAddressEditField.Value);
            
            app.Lamp.Color='green';
            drawnow;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            K=app.KSmEditField.Value;
            L=app.LnmEditField.Value;
            r=(app.dnmEditField.Value)/2;
            voltage=app.VVEditField.Value;

            L_m=L*10^-9;
            r_m=r*10^-9;

            conductance_nS = (K*((L_m/(pi*r_m^2))+(1/(2*r_m)))^-1)*10^9;
            app.cutoff_current_nA= conductance_nS*voltage*app.IFractionEditField.Value;
            app.InAEditField.Value=app.cutoff_current_nA;
            
            app.ElectricFieldVnmEditField.Value=voltage/L;
            
            if app.ElectricFieldVnmEditField.Value>1
                app.Lamp_3.Color='red';
                drawnow
            else
                app.Lamp_3.Color='green';
                drawnow
            end
            
            if app.DualThresholdCheckBox.Value==1             
                voltage=app.VVEditField.Value*app.VFEditField.Value;
                app.cutoff_current_DT_nA = conductance_nS*voltage;
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.CutoffCurrentnAEditField.Value=app.cutoff_current_nA;
            app.TargetVoltageVEditField.Value=app.VVEditField.Value;
            
            app.KSmEditField_2.Value=app.KSmEditField.Value;%in S/m
            app.LnmEditField_2.Value=app.LnmEditField.Value;%in nm
        end

        % Button pushed function: CalculateDiameterButton
        function CalculateDiameterButtonPushed(app, event)
        %calculating pore dimater
        
        G=app.GnSEditField_2.Value;%in nS
        k=app.KSmEditField_2.Value;%in S/m
        L=app.LnmEditField_2.Value;%in nm
        syms r
        EQAN=G==k*((L/(pi*(r^2)))+(1/(2*r)))^-1;
        S=solve(EQAN,r,'Real',true);
        S_solved=vpa(S);
        app.dnmEditField_2.Value=double(round(2*max(S_solved),1));  
        end

        % Button pushed function: ClearConnectionButton
        function ClearConnectionButtonPushed(app, event)
            clear all;
            app.Lamp.Color='green';
            drawnow;
        end

        % Value changed function: DualThresholdCheckBox
        function DualThresholdCheckBoxValueChanged(app, event)
            if app.DualThresholdCheckBox.Value==1
                set(app.VFEditField,'Enable', 'on')
                set(app.IFEditField,'Enable', 'on')
            else
                set(app.VFEditField,'Enable', 'off')
                set(app.IFEditField,'Enable', 'off')
            end
        end

        % Value changed function: TargetVoltageVEditField
        function TargetVoltageVEditFieldValueChanged(app, event)
            app.AppliedVVEditField.Value=app.TargetVoltageVEditField.Value;
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            app.amplification_ratio=1+(app.RFeedbackMohmEditField.Value/app.RInputMOhmEditField.Value);
            app.DACmVEditField.Value=(app.AppliedVVEditField.Value/app.amplification_ratio)*1000;
            
            test_resistance=(app.TestResistorMOhmEditField.Value)*10^6;
            pore_current=(app.AppliedVVEditField.Value/test_resistance);
            
            app.OPAMP2mVEditField.Value=-pore_current*(app.RInputTIAMOhmEditField.Value*10^6)*1000;
            app.OPAMP3mVEditField.Value=-app.OPAMP2mVEditField.Value;
            
            app.ADS1115A0A1mVEditField.Value=app.OPAMP3mVEditField.Value/app.VDividerCurrentEditField.Value;
            app.ADS1115A2A3mVEditField.Value=app.DACmVEditField.Value;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.A_Lamp.Color='magenta';
            drawnow;

            app.MeanInAEditField.Value=0;
            app.TargetInAEditField.Value=0;
            
            %rms and std
            app.RMSVEditField.Value=0;
            app.RMSnAEditField.Value=0;
            app.StdmVEditField.Value=0;
            app.StdnAEditField.Value=0;
            
            %Ratios
            app.amplification_ratio=1+(app.RFeedbackMohmEditField.Value/app.RInputMOhmEditField.Value);            
            
            %Voltage Setting
            target_voltage=app.TargetVoltageVEditField.Value;%in volts
            output_Voltage=target_voltage/app.amplification_ratio;   
            
            %voltage application from the external DAC          
            increment_firt_bit=app.FBVDACEditField.Value;
            increment_second_bit=increment_firt_bit/256;

            first_bit_val=floor(output_Voltage/increment_firt_bit);

            remainder=output_Voltage-(increment_firt_bit*first_bit_val);
            second_bit_val=floor(remainder/increment_second_bit);

            write(app.DAC,[1 0 0], 'uint8');%outputs a voltage of 0.3125V from the DAC. Very essential step where the setup seems to get rid of any residual currents in the system
            pause(1);
            write(app.DAC,[first_bit_val second_bit_val 0], 'uint8');
                                 
            if app.DualThresholdCheckBox.Value==1
                secondary_output_V=output_Voltage*app.VFEditField.Value; 
                I_switch_to_secondary_I=app.CutoffCurrentnAEditField.Value*app.IFEditField.Value;
                
                %displaying the values
                app.SecondaryVVEditField.Value=target_voltage*app.VFEditField.Value;
                app.SwitchtoSecondaryInAEditField.Value=I_switch_to_secondary_I;
                app.SecondaryICutoffEditField.Value=app.cutoff_current_DT_nA;
                
                %for the voltage output of the dual current threshold operation
                first_bit_val_DT=floor(secondary_output_V/increment_firt_bit);
                remainder=secondary_output_V-(increment_firt_bit*first_bit_val_DT);
                second_bit_val_DT=floor(remainder/increment_second_bit);
            end
            
            %Settingup and reading from the ADS-1115                     
            ADS1115_config_string_Ch_0_1=strcat(app.start_coversion_bit,app.config_input_channels_0_1,app.gain_bits,app.operating_mode,app.sample_rate,app.bits_4_to_0);
            swap_bytes_Ch_0_1=swapbytes(uint16(bin2dec(ADS1115_config_string_Ch_0_1)));

            ADS1115_config_string_Ch_2_3=strcat(app.start_coversion_bit,app.config_input_channels_2_3,app.gain_bits,app.operating_mode,app.sample_rate,app.bits_4_to_0);
            swap_bytes_Ch_2_3=swapbytes(uint16(bin2dec(ADS1115_config_string_Ch_2_3)));                                             
            
            %Plots
            LW=1.3;%line width of plots
            plot(app.UIAxes,0,0);
            It_Plot=animatedline(app.UIAxes,0,0,'Color','k','LineWidth',LW);
            app.UIAxes.XLabel.String = 'Time (s)';
            
            %plot(app.UIAxes2,0,0);           
            %Vt_Plot=animatedline(app.UIAxes2,0,0,'Color','r','LineWidth',LW);
            
            
            %Storage Arrays
            app.v_array=zeros(1,2);
            app.t_array=zeros(1,1);
            
    %Main While Loop
    time_in=datetime('now');
    i=0;
    sc=0;
    
    elapsed_time=0;
    app.cutoff_I=app.CutoffCurrentnAEditField.Value*1000;%this is the case until time is less than the latency time, afterward, it returns to app.CutoffCurrentnAEditField.Value
    
    while app.stop_CBD==0 && elapsed_time<app.TimeoutsEditField.Value && app.CurrentnAEditField.Value<app.cutoff_I
         
        i=i+1;          
        %ADS-1115 configurations and measurements
        writeRegister(app.ADS1115,1,swap_bytes_Ch_2_3,'uint16');
        data_Ch_2_3 = swapbytes(uint16(readRegister(app.ADS1115,0,'uint16')));
        voltage_Ch_2_3 = double(data_Ch_2_3)*app.upper_Full_Scale_Range/32768;
        
        %calculations based on the first differential channel
        app.NanoporeVEditField.Value=voltage_Ch_2_3*app.VDividerVoltageEditField.Value*(app.amplification_ratio);       
        
        writeRegister(app.ADS1115,1,swap_bytes_Ch_0_1,'uint16');
        swbp=swapbytes(uint16(readRegister(app.ADS1115,0,'uint16')));
        if swbp>32768 %zero occurs at 2^15
            data_Ch_0_1 = 65536-swbp;
        else
            data_Ch_0_1=swbp;
        end        
        voltage_Ch_0_1 = double(data_Ch_0_1)*app.upper_Full_Scale_Range/32768;
        
        %calculations based on the second differential channel
        current_through_nanopore=voltage_Ch_0_1/(app.RInputTIAMOhmEditField.Value*10^6);
        app.CurrentnAEditField.Value=(current_through_nanopore*app.VDividerCurrentEditField.Value*10^9);      
        
        app.GnSEditField.Value=app.CurrentnAEditField.Value/app.NanoporeVEditField.Value;%in nS
        app.RMohmEditField.Value=(1/app.GnSEditField.Value)*1000;%in Mega Ohm
        
        time_now=datetime('now');
        elapsed_time=seconds(time_now-time_in);
    
        if elapsed_time<app.LatencysEditField.Value
            app.cutoff_I=app.CutoffCurrentnAEditField.Value*1000;
        else
            if app.DualThresholdCheckBox.Value==1
                if app.CurrentnAEditField.Value>I_switch_to_secondary_I
                    app.cutoff_I=app.cutoff_current_DT_nA;
                    sc=sc+1;
                    
                    %updating values in the front panel
                    app.TargetVoltageVEditField.Value=app.SecondaryVVEditField.Value;
                    app.CutoffCurrentnAEditField.Value=app.cutoff_I;
                    %write(app.DAC,[first_bit_val_DT second_bit_val_DT 0], 'uint8');
                else
                    app.cutoff_I=app.CutoffCurrentnAEditField.Value;
                end
            else
                app.cutoff_I=app.CutoffCurrentnAEditField.Value;
            end
        end
        
        if sc==1
            write(app.DAC,[first_bit_val_DT second_bit_val_DT 0], 'uint8');
            app.A_Lamp.Color='yellow';
            drawnow;
            pause(0.5);%a pause of 500 miliseconds for the system to settle down
        end
        
        app.t_array(i,1)=elapsed_time;
        app.v_array(i,1)=app.CurrentnAEditField.Value;
        app.v_array(i,2)=app.NanoporeVEditField.Value;
        
        if length(app.v_array(:,2))>10%we need the last 10 readings to calculate the rms of the voltage
            voltage_array=app.v_array(end-10:end,2);
            I_array=app.v_array(end-10:end,1);
            
            app.RMSVEditField.Value=rms(voltage_array);
            app.RMSnAEditField.Value=rms(I_array);
            app.StdmVEditField.Value=std(voltage_array)*1000;
            app.StdnAEditField.Value=std(I_array);
        end
        
            %plotting
            addpoints(It_Plot,elapsed_time,app.CurrentnAEditField.Value);
            %addpoints(Vt_Plot,elapsed_time,app.NanoporeVEditField.Value);
            drawnow
    end
    
            app.stop_CBD=0;
            write(app.DAC,[0 0 0], 'uint8');
            app.A_Lamp.Color='green';
            drawnow;
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stop_CBD=1;            
        end

        % Button pushed function: ENLARGEButton
        function ENLARGEButtonPushed(app, event)
            app.Lamp_2.Color='yellow';
            drawnow;
            
            %rms and std
            app.RMSVEditField.Value=0;
            app.RMSnAEditField.Value=0;
            app.StdmVEditField.Value=0;
            app.StdnAEditField.Value=0;
            
            %Plots
            LW=1.3;%line width of plots
            plot(app.UIAxes,0,0);
            app.UIAxes.XLabel.String = 'Time (s)';
            
            %Storage Arrays
            app.v_array=zeros(1,2);
            I_array_temp_Low=zeros(1,1);
            I_array_temp_High=zeros(1,1);
            app.t_array=zeros(1,1);
            
            %Target current calculation using target diameter
            K=app.KSmEditField.Value;
            L=app.LnmEditField.Value;
            d=app.dnmEditField.Value;

            L_nm=L*10^-9;
            r_nm=(d/2)*10^-9;

            conductance_nS = (K*((L_nm/(pi*r_nm^2))+(1/(2*r_nm)))^-1)*10^9;            
            if strcmp(app.IMeasureDropDown.Value,'Low V')==1
                target_current_nA= conductance_nS*app.VLowVEditField.Value;
            elseif strcmp(app.IMeasureDropDown.Value,'High V')==1
                target_current_nA= conductance_nS*app.VHighVEditField.Value;
            else
                target_current_nA= conductance_nS*app.VHighVEditField.Value;%starting with the high cycle
                target_current_nA_LOW= conductance_nS*app.VLowVEditField.Value;
                target_current_High= conductance_nS*app.VHighVEditField.Value;
            end                        
            app.TargetInAEditField.Value=target_current_nA;
            app.amplification_ratio=1+(app.RFeedbackMohmEditField.Value/app.RInputMOhmEditField.Value);            
            
            %Settingup and reading from the ADS-1115                     
            ADS1115_config_string_Ch_0_1=strcat(app.start_coversion_bit,app.config_input_channels_0_1,app.gain_bits,app.operating_mode,app.sample_rate,app.bits_4_to_0);
            swap_bytes_Ch_0_1=swapbytes(uint16(bin2dec(ADS1115_config_string_Ch_0_1)));

            ADS1115_config_string_Ch_2_3=strcat(app.start_coversion_bit,app.config_input_channels_2_3,app.gain_bits,app.operating_mode,app.sample_rate,app.bits_4_to_0);
            swap_bytes_Ch_2_3=swapbytes(uint16(bin2dec(ADS1115_config_string_Ch_2_3)));                         
            
            %Main While Loop
            i=0;
            low_current=0;
            high_current=0;
            present_I=0;  
            grand_elapsed_time=0;
            time_in=datetime('now');
            
    while target_current_nA>present_I && app.stop_enlarging==0                
        
        %HIGH CYCLE
        high_index=0;
        time_in_cycle=datetime('now');
        elapsed_time=0;
        if strcmp(app.IMeasureDropDown.Value,'Both')==1
            app.TargetInAEditField.Value=target_current_High;
        end
                
        while elapsed_time<app.HighTimesEditField.Value
            i=i+1;
            
           %Voltage Setting
           target_voltage=app.VHighVEditField.Value;
           output_Voltage=target_voltage/app.amplification_ratio;
           %voltage application from the external DAC
           increment_firt_bit=app.FBVDACEditField.Value;
           increment_second_bit=increment_firt_bit/256;
           first_bit_val=floor(output_Voltage/increment_firt_bit);
           remainder=output_Voltage-(increment_firt_bit*first_bit_val);
           second_bit_val=floor(remainder/increment_second_bit);
           write(app.DAC,[first_bit_val second_bit_val 0], 'uint8'); 
           
           writeRegister(app.ADS1115,1,swap_bytes_Ch_2_3,'uint16');
           data_Ch_2_3 = swapbytes(uint16(readRegister(app.ADS1115,0,'uint16')));
           voltage_Ch_2_3 = double(data_Ch_2_3)*app.upper_Full_Scale_Range/32768;
        
           %calculations based on the first differential channel
           app.NanoporeVEditField.Value=voltage_Ch_2_3*app.VDividerVoltageEditField.Value*app.amplification_ratio;       
        
           writeRegister(app.ADS1115,1,swap_bytes_Ch_0_1,'uint16');
           swbp=swapbytes(uint16(readRegister(app.ADS1115,0,'uint16')));
                if swbp>32768 %zero occurs at 2^15
                    data_Ch_0_1 = 65536-swbp;
                else
                    data_Ch_0_1=swbp;
                end        
           voltage_Ch_0_1 = double(data_Ch_0_1)*app.upper_Full_Scale_Range/32768;
        
           %calculations based on the second differential channel
           current_through_nanopore=voltage_Ch_0_1/(app.RInputTIAMOhmEditField.Value*10^6);
           app.CurrentnAEditField.Value=(current_through_nanopore*app.VDividerCurrentEditField.Value*10^9); 
        
           %Storing current in Array
           high_index=high_index+1;
           I_array_temp_High(high_index)=app.CurrentnAEditField.Value;
           
           app.GnSEditField.Value=app.CurrentnAEditField.Value/app.NanoporeVEditField.Value;%in nS
           app.RMohmEditField.Value=(1/app.GnSEditField.Value)*1000;%in Mega Ohm
                
           %Plotting
           grand_time_now=datetime('now');
           grand_elapsed_time=seconds(grand_time_now-time_in);
            
           app.t_array(i,1)=grand_elapsed_time;
           app.v_array(i,1)=app.CurrentnAEditField.Value;
           app.v_array(i,2)=app.NanoporeVEditField.Value;
        
           plot(app.UIAxes,app.t_array,app.v_array(:,1),'k','LineWidth',LW)
           time_now=datetime('now');
           elapsed_time=seconds(time_now-time_in_cycle);
        end
        
        half_array_size=floor(length(I_array_temp_High)/2);
        high_current=mean(I_array_temp_High(half_array_size:end));
        app.MeanInAEditField.Value=high_current;
        I_array_temp_High=zeros(1,2);               

        %LOW CYCLE
        low_index=0;
        time_in_cycle=datetime('now');
        elapsed_time=0;
        if strcmp(app.IMeasureDropDown.Value,'Both')==1
            app.TargetInAEditField.Value=target_current_nA_LOW;
        end
        
        while elapsed_time<app.LowTimesEditField.Value
            i=i+1;
            
           %Voltage Setting
           target_voltage=app.VLowVEditField.Value;
           output_Voltage=target_voltage/app.amplification_ratio;
           %voltage application from the external DAC
           increment_firt_bit=app.FBVDACEditField.Value;
           increment_second_bit=increment_firt_bit/256;
           first_bit_val=floor(output_Voltage/increment_firt_bit);
           remainder=output_Voltage-(increment_firt_bit*first_bit_val);
           second_bit_val=floor(remainder/increment_second_bit);
           write(app.DAC,[first_bit_val second_bit_val 0], 'uint8');                      
           
           writeRegister(app.ADS1115,1,swap_bytes_Ch_2_3,'uint16');
           data_Ch_2_3 = swapbytes(uint16(readRegister(app.ADS1115,0,'uint16')));
           voltage_Ch_2_3 = double(data_Ch_2_3)*app.upper_Full_Scale_Range/32768;
        
           %calculations based on the first differential channel
           app.NanoporeVEditField.Value=voltage_Ch_2_3*app.VDividerVoltageEditField.Value*app.amplification_ratio;       
        
           writeRegister(app.ADS1115,1,swap_bytes_Ch_0_1,'uint16');
           swbp=swapbytes(uint16(readRegister(app.ADS1115,0,'uint16')));
                if swbp>32768 %zero occurs at 2^15
                    data_Ch_0_1 = 65536-swbp;
                else
                    data_Ch_0_1=swbp;
                end        
           voltage_Ch_0_1 = double(data_Ch_0_1)*app.upper_Full_Scale_Range/32768;
        
           %calculations based on the second differential channel
           current_through_nanopore=voltage_Ch_0_1/(app.RInputTIAMOhmEditField.Value*10^6);
           app.CurrentnAEditField.Value=(current_through_nanopore*app.VDividerCurrentEditField.Value*10^9); 
           
           %Storing current in Array
           low_index=low_index+1;
           I_array_temp_Low(low_index)=app.CurrentnAEditField.Value;
           
           app.GnSEditField.Value=app.CurrentnAEditField.Value/app.NanoporeVEditField.Value;%in nS
           app.RMohmEditField.Value=(1/app.GnSEditField.Value)*1000;%in Mega Ohm
        
           %Plotting
           grand_time_now=datetime('now');
           grand_elapsed_time=seconds(grand_time_now-time_in);
            
            app.t_array(i,1)=grand_elapsed_time;
            app.v_array(i,1)=app.CurrentnAEditField.Value;
            app.v_array(i,2)=app.NanoporeVEditField.Value;        
        
            plot(app.UIAxes,app.t_array,app.v_array(:,1),'k','LineWidth',LW)
            
            time_now=datetime('now');
            elapsed_time=seconds(time_now-time_in_cycle);
        end
        
        half_array_size=floor(length(I_array_temp_Low)/2);
        low_current=mean(I_array_temp_Low(half_array_size:end));
        app.MeanInAEditField.Value=low_current;
        I_array_temp_Low=zeros(1,2);
        
        if strcmp(app.IMeasureDropDown.Value,'Low V')==1
                present_I=low_current;
        elseif strcmp(app.IMeasureDropDown.Value,'High V')==1
                present_I=high_current;
        else            
            if low_current>target_current_nA_LOW || high_current>target_current_High
                present_I=target_current_nA*2;%to end the main while loop
            end
        end
    end
    
            app.stop_enlarging=0;
            writePWMVoltage(app.arduino_device, 'D5', 0)
            app.Lamp_2.Color='green';
            drawnow;
        end

        % Button pushed function: TerminateButton
        function TerminateButtonPushed(app, event)
            app.stop_enlarging=1;
        end

        % Button pushed function: GetIVButton
        function GetIVButtonPushed(app, event)
            app.IV_Lamp.Color='yellow';
            drawnow;
            
            app.GSlopenSEditField.Value=0;
            
            %rms and std
            app.RMSVEditField.Value=0;
            app.RMSnAEditField.Value=0;
            app.StdmVEditField.Value=0;
            app.StdnAEditField.Value=0;
            
            %other controls
            app.GnSEditField.Value=0;
            app.RMohmEditField.Value=0;
            
            %Plots
            plot(app.UIAxes,0,0);
            app.UIAxes.XLabel.String = 'Voltage (V)';
            app.amplification_ratio=1+(app.RFeedbackMohmEditField.Value/app.RInputMOhmEditField.Value); 
            
            %I-V setup
            steps=app.StepsEditField.Value;
            dI=(app.FinalVVEditField.Value-app.StartingVVEditField.Value)/steps;
            target_voltage=app.StartingVVEditField.Value;
            app.mean_I_array=zeros(steps,1);
            app.mean_V_array=zeros(steps,1);
            app.std_I_array=zeros(steps,1);
            app.std_V_array=zeros(steps,1);
            
            %Settingup and reading from the ADS-1115                     
            ADS1115_config_string_Ch_0_1=strcat(app.start_coversion_bit,app.config_input_channels_0_1,app.gain_bits,app.operating_mode,app.sample_rate,app.bits_4_to_0);
            swap_bytes_Ch_0_1=swapbytes(uint16(bin2dec(ADS1115_config_string_Ch_0_1)));

            ADS1115_config_string_Ch_2_3=strcat(app.start_coversion_bit,app.config_input_channels_2_3,app.gain_bits,app.operating_mode,app.sample_rate,app.bits_4_to_0);
            swap_bytes_Ch_2_3=swapbytes(uint16(bin2dec(ADS1115_config_string_Ch_2_3))); 
  
     
   for i=1:steps
        time_in_cycle=datetime('now');
        elapsed_time=0;
        I_array_temp=zeros(1,1);
        V_array_temp=zeros(1,1);
        I_index=0;
        
        %write(app.DAC,[1 0 0], 'uint8');%outputs a voltage of 0.3125V from the DAC. Very essential step where the setup seems to get rid of any residual currents in the system
        %pause(1);
        if app.stop_IV==1
            break
        end
        
        while elapsed_time<3
            
           %Voltage Setting
           output_Voltage=target_voltage/app.amplification_ratio;
           %voltage application from the external DAC
           increment_firt_bit=app.FBVDACEditField.Value;
           increment_second_bit=increment_firt_bit/256;
           first_bit_val=floor(output_Voltage/increment_firt_bit);
           remainder=output_Voltage-(increment_firt_bit*first_bit_val);
           second_bit_val=floor(remainder/increment_second_bit);
           
           write(app.DAC,[first_bit_val second_bit_val 0], 'uint8');
           %pause(0.5);
           
           writeRegister(app.ADS1115,1,swap_bytes_Ch_2_3,'uint16');
           data_Ch_2_3 = swapbytes(uint16(readRegister(app.ADS1115,0,'uint16')));
           voltage_Ch_2_3 = double(data_Ch_2_3)*app.upper_Full_Scale_Range/32768;
        
           %calculations based on the first differential channel
           app.NanoporeVEditField.Value=voltage_Ch_2_3*app.VDividerVoltageEditField.Value*app.amplification_ratio;       
        
           writeRegister(app.ADS1115,1,swap_bytes_Ch_0_1,'uint16');
           swbp=swapbytes(uint16(readRegister(app.ADS1115,0,'uint16')));
                if swbp>32768 %zero occurs at 2^15
                    data_Ch_0_1 = 65536-swbp;
                else
                    data_Ch_0_1=swbp;
                end        
           voltage_Ch_0_1 = double(data_Ch_0_1)*app.upper_Full_Scale_Range/32768;
        
           %calculations based on the second differential channel
           current_through_nanopore=voltage_Ch_0_1/(app.RInputTIAMOhmEditField.Value*10^6);
           app.CurrentnAEditField.Value=(current_through_nanopore*app.VDividerCurrentEditField.Value*10^9); 
            
           if elapsed_time>1
                I_index=I_index+1;
                I_array_temp(I_index)=app.CurrentnAEditField.Value;
                V_array_temp(I_index)=app.NanoporeVEditField.Value;
           end
           
           time_now=datetime('now');
           elapsed_time=seconds(time_now-time_in_cycle);
        end
        
        half_array_size=floor(length(I_array_temp)/2);        
        mean_current=mean(I_array_temp(half_array_size:end));
        app.mean_I_array(i)=mean_current;
        mean_Voltage=mean(V_array_temp(half_array_size:end));
        app.mean_V_array(i)=mean_Voltage; 
        
        readings=length(I_array_temp(half_array_size:end));
        std_I=std(I_array_temp(half_array_size:end))/sqrt(readings);
        std_V=std(V_array_temp(half_array_size:end))/sqrt(readings);
        app.std_I_array(i)=std_I;
        app.std_V_array(i)=std_V;
        
        target_voltage=target_voltage+dI;
        p1=errorbar(app.UIAxes,app.mean_V_array,app.mean_I_array,app.std_I_array,'m','LineWidth',1.3,'MarkerSize',10);
        p1.Marker='s';
        p1.LineStyle='none';
  end

 
    % fitting
    if app.stop_IV==0
        
    x_values=app.mean_V_array;
    y_values=app.mean_I_array;
    poly_fit = polyfit(x_values,y_values,1);
    x_lienar_fitp=x_values(1):0.01:x_values(end)+0.1;
    y_lienar_fitp = polyval(poly_fit,x_lienar_fitp');

    app.GSlopenSEditField.Value=round(poly_fit(1),1);
    
    %calculating pore dimater
    G=app.GSlopenSEditField.Value;%in nS
    if G>0
        k=app.KSmEditField.Value;%in S/m
        L=app.LnmEditField.Value;%in nm
        syms r
        EQAN=G==k*((L/(pi*(r^2)))+(1/(2*r)))^-1;
        S=solve(EQAN,r,'Real',true);
        S_solved=vpa(S);
        pore_dia=double(round(2*max(S_solved),1));
        app.PorediameternmEditField.Value=num2str(pore_dia);
    else
        app.PorediameternmEditField.Value="-G";
    end
    
    app.GnSEditField_2.Value=app.GSlopenSEditField.Value;%in nS
    
    %Final Plotting
    p1=errorbar(app.UIAxes,app.mean_V_array,app.mean_I_array,app.std_I_array,'m','LineWidth',1.3,'MarkerSize',10);
    %errorbar(x,y,err)
    p1.Marker='s';
    p1.LineStyle='none';
    hold (app.UIAxes,'on')
    plot(app.UIAxes,x_lienar_fitp,y_lienar_fitp,'k--','LineWidth',1.5);
    hold (app.UIAxes,'off')
    end
    
    app.stop_IV=0;
    app.IV_Lamp.Color='green';
    drawnow;
        end

        % Button pushed function: STOPIVButton
        function STOPIVButtonPushed(app, event)
            app.stop_IV=1;
        end

        % Button pushed function: ExportIVButton
        function ExportIVButtonPushed(app, event)
            app.IV_Export_Lamp.Color='yellow';
            drawnow;
            
            prompt = {'Save As'};
            dlgtitle = 'I-V Curve';
            IV_file_name_base=strcat(app.ChipNameEditField.Value,'_IV_Curve');
            definput = {IV_file_name_base};
            opts.Interpreter = 'tex';
            answer = inputdlg(prompt,dlgtitle,[1 60],definput,opts);
            time_stamp=strcat(datestr(now,'HH'),"_",datestr(now,'MM'),"_",datestr(now,'SS'));
            save_file_name=strcat(answer,"_",time_stamp,".xlsx");
            
            header={'Voltage (V)','std(V)','Current (nA)','std(nA)'};
            
            path=pwd;%current matlab path
            save_array=[app.mean_V_array(:,1) app.std_V_array app.mean_I_array(:,1) app.std_I_array];
            xlswrite(char(strcat(path,'\',save_file_name)),save_array,'sheet1','A2');
            xlswrite(char(strcat(path,'\',save_file_name)),header,'sheet1','A1');
            
            app.IV_Export_Lamp.Color='green';
            drawnow;
        end

        % Button pushed function: SAVEDATAButton
        function SAVEDATAButtonPushed(app, event)
            app.SAVE_LAMPLamp.Color='yellow';
            drawnow;
            
            prompt = {'Save As'};
            dlgtitle = 'CBD Current Trace';
            file_name_base=strcat(app.ChipNameEditField.Value,'_',num2str(app.TargetVoltageVEditField.Value),'V_CBD_Trace');
            definput = {file_name_base};
            opts.Interpreter = 'tex';
            answer = inputdlg(prompt,dlgtitle,[1 60],definput,opts);
            time_stamp=strcat(datestr(now,'HH'),"_",datestr(now,'MM'),"_",datestr(now,'SS'));
            save_file_name=strcat(answer,"_",time_stamp,".xlsx");
            
            header={'Time (s)','Current (nA)','Voltage (V)'};
            
            path=pwd;%current matlab path
            save_array=[app.t_array(:,1) app.v_array(:,1) app.v_array(:,2)];
            xlswrite(char(strcat(path,'\',save_file_name)),save_array,'sheet1','A2');
            xlswrite(char(strcat(path,'\',save_file_name)),header,'sheet1','A1');
            
            app.SAVE_LAMPLamp.Color='green';
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainControlsTab_2 = uitab(app.TabGroup);
            app.MainControlsTab_2.Title = 'Main Controls';
            app.TabGroup2 = uitabgroup(app.MainControlsTab_2);
            app.ConnectionsControlsTab = uitab(app.TabGroup2);
            app.ConnectionsControlsTab.Title = 'Connections/Controls';
            app.ConnectionsPanel = uipanel(app.ConnectionsControlsTab);
            app.ConnectionsPanel.Title = 'Connections';
            app.ConnectButton = uibutton(app.ConnectionsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp = uilamp(app.ConnectionsPanel);
            app.ArduinoBoardDropDownLabel = uilabel(app.ConnectionsPanel);
            app.ArduinoBoardDropDownLabel.Text = 'Arduino Board';
            app.ArduinoBoardDropDown = uidropdown(app.ConnectionsPanel);
            app.ArduinoBoardDropDown.Items = {'Uno'};
            app.ArduinoBoardDropDown.Value = 'Uno';
            app.ClearConnectionButton = uibutton(app.ConnectionsPanel, 'push');
            app.ClearConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearConnectionButtonPushed, true);
            app.ClearConnectionButton.Text = 'Clear Connection';
            app.COMPORTDropDownLabel = uilabel(app.ConnectionsPanel);
            app.COMPORTDropDownLabel.Text = 'COM PORT';
            app.COMPORTDropDown = uidropdown(app.ConnectionsPanel);
            app.COMPORTDropDown.Items = {'Select...'};
            app.COMPORTDropDown.DropDownOpeningFcn = createCallbackFcn(app, @COMPORTDropDownOpening, true);
            app.COMPORTDropDown.Value = 'Select...';
            app.Lamp_CP = uilamp(app.ConnectionsPanel);
            app.TabGroup4 = uitabgroup(app.ConnectionsControlsTab);
            app.BreakdownTab = uitab(app.TabGroup4);
            app.BreakdownTab.Title = 'Breakdown';
            app.TargetVoltageVEditFieldLabel = uilabel(app.BreakdownTab);
            app.TargetVoltageVEditFieldLabel.Text = 'Target Voltage (V)';
            app.TargetVoltageVEditField = uieditfield(app.BreakdownTab, 'numeric');
            app.TargetVoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @TargetVoltageVEditFieldValueChanged, true);
            app.CutoffCurrentnAEditFieldLabel = uilabel(app.BreakdownTab);
            app.CutoffCurrentnAEditFieldLabel.Text = 'Cutoff Current (nA)';
            app.CutoffCurrentnAEditField = uieditfield(app.BreakdownTab, 'numeric');
            app.LatencysEditFieldLabel = uilabel(app.BreakdownTab);
            app.LatencysEditFieldLabel.Text = 'Latency (s)';
            app.LatencysEditField = uieditfield(app.BreakdownTab, 'numeric');
            app.TimeoutsEditFieldLabel = uilabel(app.BreakdownTab);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s)';
            app.TimeoutsEditField = uieditfield(app.BreakdownTab, 'numeric');
            app.STARTButton = uibutton(app.BreakdownTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.BreakdownTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.A_Lamp = uilamp(app.BreakdownTab);
            app.SAVEDATAButton = uibutton(app.BreakdownTab, 'push');
            app.SAVEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATAButton.Text = 'SAVE DATA';
            app.SAVE_LAMPLamp = uilamp(app.BreakdownTab);
            app.FBVDACEditFieldLabel = uilabel(app.BreakdownTab);
            app.FBVDACEditFieldLabel.Text = 'FBV (DAC)';
            app.FBVDACEditField = uieditfield(app.BreakdownTab, 'numeric');
            app.DualThresholdCheckBox = uicheckbox(app.BreakdownTab);
            app.DualThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @DualThresholdCheckBoxValueChanged, true);
            app.DualThresholdCheckBox.Text = 'Dual Threshold';
            app.VFLabel = uilabel(app.BreakdownTab);
            app.VFLabel.Text = 'V-F';
            app.VFEditField = uieditfield(app.BreakdownTab, 'numeric');
            app.VFEditField.Enable = 'off';
            app.VFEditField.Value = 0.5;
            app.IFLabel = uilabel(app.BreakdownTab);
            app.IFLabel.Text = 'I-F';
            app.IFEditField = uieditfield(app.BreakdownTab, 'numeric');
            app.IFEditField.Enable = 'off';
            app.IFEditField.Value = 0.5;
            app.ChipNameEditFieldLabel = uilabel(app.BreakdownTab);
            app.ChipNameEditFieldLabel.Visible = 'off';
            app.ChipNameEditFieldLabel.Text = 'Chip Name';
            app.ChipNameEditField = uieditfield(app.BreakdownTab, 'text');
            app.ChipNameEditField.Value = 'Chip1';
            app.TargetTab = uitab(app.TabGroup4);
            app.TargetTab.Title = 'Target';
            app.CalculateButton = uibutton(app.TargetTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UpdateButton = uibutton(app.TargetTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.LnmEditFieldLabel = uilabel(app.TargetTab);
            app.LnmEditFieldLabel.Text = 'L (nm)';
            app.LnmEditField = uieditfield(app.TargetTab, 'numeric');
            app.dnmEditFieldLabel = uilabel(app.TargetTab);
            app.dnmEditFieldLabel.Text = 'd (nm)';
            app.dnmEditField = uieditfield(app.TargetTab, 'numeric');
            app.KSmEditFieldLabel = uilabel(app.TargetTab);
            app.KSmEditFieldLabel.Text = 'K (S/m)';
            app.KSmEditField = uieditfield(app.TargetTab, 'numeric');
            app.VVEditFieldLabel = uilabel(app.TargetTab);
            app.VVEditFieldLabel.Text = 'V (V)';
            app.VVEditField = uieditfield(app.TargetTab, 'numeric');
            app.InAEditFieldLabel = uilabel(app.TargetTab);
            app.InAEditFieldLabel.Text = 'I (nA)';
            app.InAEditField = uieditfield(app.TargetTab, 'numeric');
            app.InAEditField.Editable = 'off';
            app.ElectricFieldVnmEditFieldLabel = uilabel(app.TargetTab);
            app.ElectricFieldVnmEditFieldLabel.Text = 'Electric Field (V/nm)';
            app.ElectricFieldVnmEditField = uieditfield(app.TargetTab, 'numeric');
            app.Lamp_3Label = uilabel(app.TargetTab);
            app.Lamp_3Label.Visible = 'off';
            app.Lamp_3Label.Text = 'Lamp';
            app.Lamp_3 = uilamp(app.TargetTab);
            app.IFractionEditFieldLabel = uilabel(app.TargetTab);
            app.IFractionEditFieldLabel.Text = 'I Fraction';
            app.IFractionEditField = uieditfield(app.TargetTab, 'numeric');
            app.IFractionEditField.Limits = [0 1];
            app.IFractionEditField.Value = 1;
            app.GrowthTab = uitab(app.TabGroup4);
            app.GrowthTab.Title = 'Growth';
            app.VLowVEditFieldLabel = uilabel(app.GrowthTab);
            app.VLowVEditFieldLabel.Text = 'V Low (V)';
            app.VLowVEditField = uieditfield(app.GrowthTab, 'numeric');
            app.VHighVEditFieldLabel = uilabel(app.GrowthTab);
            app.VHighVEditFieldLabel.Text = 'V High (V)';
            app.VHighVEditField = uieditfield(app.GrowthTab, 'numeric');
            app.LowTimesEditFieldLabel = uilabel(app.GrowthTab);
            app.LowTimesEditFieldLabel.Text = 'Low Time (s)';
            app.LowTimesEditField = uieditfield(app.GrowthTab, 'numeric');
            app.HighTimesEditFieldLabel = uilabel(app.GrowthTab);
            app.HighTimesEditFieldLabel.Text = 'High Time (s)';
            app.HighTimesEditField = uieditfield(app.GrowthTab, 'numeric');
            app.ENLARGEButton = uibutton(app.GrowthTab, 'push');
            app.ENLARGEButton.ButtonPushedFcn = createCallbackFcn(app, @ENLARGEButtonPushed, true);
            app.ENLARGEButton.Text = 'ENLARGE';
            app.TerminateButton = uibutton(app.GrowthTab, 'push');
            app.TerminateButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton.Text = 'Terminate';
            app.Lamp_2 = uilamp(app.GrowthTab);
            app.TargetInAEditFieldLabel = uilabel(app.GrowthTab);
            app.TargetInAEditFieldLabel.Text = 'Target I (nA)';
            app.TargetInAEditField = uieditfield(app.GrowthTab, 'numeric');
            app.TargetInAEditField.Editable = 'off';
            app.MeanInAEditFieldLabel = uilabel(app.GrowthTab);
            app.MeanInAEditFieldLabel.Text = 'Mean I (nA)';
            app.MeanInAEditField = uieditfield(app.GrowthTab, 'numeric');
            app.MeanInAEditField.Editable = 'off';
            app.IMeasureDropDownLabel = uilabel(app.GrowthTab);
            app.IMeasureDropDownLabel.Text = 'I Measure';
            app.IMeasureDropDown = uidropdown(app.GrowthTab);
            app.IMeasureDropDown.Items = {'Low V', 'High V', 'Both'};
            app.IMeasureDropDown.Value = 'Low V';
            app.IVTab = uitab(app.TabGroup4);
            app.IVTab.Title = 'I-V';
            app.StartingVVEditFieldLabel = uilabel(app.IVTab);
            app.StartingVVEditFieldLabel.Text = 'Starting V (V)';
            app.StartingVVEditField = uieditfield(app.IVTab, 'numeric');
            app.FinalVVEditFieldLabel = uilabel(app.IVTab);
            app.FinalVVEditFieldLabel.Text = 'Final V (V)';
            app.FinalVVEditField = uieditfield(app.IVTab, 'numeric');
            app.StepsEditFieldLabel = uilabel(app.IVTab);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.IVTab, 'numeric');
            app.GetIVButton = uibutton(app.IVTab, 'push');
            app.GetIVButton.ButtonPushedFcn = createCallbackFcn(app, @GetIVButtonPushed, true);
            app.GetIVButton.Text = 'Get I-V';
            app.IV_Lamp = uilamp(app.IVTab);
            app.GSlopenSEditFieldLabel = uilabel(app.IVTab);
            app.GSlopenSEditFieldLabel.Text = 'G Slope (nS)';
            app.GSlopenSEditField = uieditfield(app.IVTab, 'numeric');
            app.ExportIVButton = uibutton(app.IVTab, 'push');
            app.ExportIVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportIVButtonPushed, true);
            app.ExportIVButton.Text = 'Export I-V';
            app.IV_Export_Lamp = uilamp(app.IVTab);
            app.PorediameternmEditFieldLabel = uilabel(app.IVTab);
            app.PorediameternmEditFieldLabel.Text = 'Pore diameter (nm)';
            app.PorediameternmEditField = uieditfield(app.IVTab, 'text');
            app.STOPIVButton = uibutton(app.IVTab, 'push');
            app.STOPIVButton.ButtonPushedFcn = createCallbackFcn(app, @STOPIVButtonPushed, true);
            app.STOPIVButton.Text = 'STOP IV';
            app.DiameterTab = uitab(app.TabGroup4);
            app.DiameterTab.Title = 'Diameter';
            app.LnmEditField_2Label = uilabel(app.DiameterTab);
            app.LnmEditField_2Label.Text = 'L (nm)';
            app.LnmEditField_2 = uieditfield(app.DiameterTab, 'numeric');
            app.KSmEditField_2Label = uilabel(app.DiameterTab);
            app.KSmEditField_2Label.Text = 'K (S/m)';
            app.KSmEditField_2 = uieditfield(app.DiameterTab, 'numeric');
            app.GnSEditField_2Label = uilabel(app.DiameterTab);
            app.GnSEditField_2Label.Text = 'G (nS)';
            app.GnSEditField_2 = uieditfield(app.DiameterTab, 'numeric');
            app.dnmEditField_2Label = uilabel(app.DiameterTab);
            app.dnmEditField_2Label.Text = 'd (nm)';
            app.dnmEditField_2 = uieditfield(app.DiameterTab, 'numeric');
            app.dnmEditField_2.Editable = 'off';
            app.CalculateDiameterButton = uibutton(app.DiameterTab, 'push');
            app.CalculateDiameterButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDiameterButtonPushed, true);
            app.CalculateDiameterButton.Text = 'Calculate Diameter';
            app.ResistorsTab = uitab(app.TabGroup2);
            app.ResistorsTab.Title = 'Resistors';
            app.ResistorsofOPAMPsPanel = uipanel(app.ResistorsTab);
            app.ResistorsofOPAMPsPanel.Title = 'Resistors of OP-AMPs';
            app.RFeedbackMohmEditFieldLabel = uilabel(app.ResistorsofOPAMPsPanel);
            app.RFeedbackMohmEditFieldLabel.Text = 'R Feedback (Mohm)';
            app.RFeedbackMohmEditField = uieditfield(app.ResistorsofOPAMPsPanel, 'numeric');
            app.RInputMOhmEditFieldLabel = uilabel(app.ResistorsofOPAMPsPanel);
            app.RInputMOhmEditFieldLabel.Text = 'R Input (MOhm)';
            app.RInputMOhmEditField = uieditfield(app.ResistorsofOPAMPsPanel, 'numeric');
            app.RInputTIAMOhmEditFieldLabel = uilabel(app.ResistorsofOPAMPsPanel);
            app.RInputTIAMOhmEditFieldLabel.Text = 'R Input TIA (MOhm)';
            app.RInputTIAMOhmEditField = uieditfield(app.ResistorsofOPAMPsPanel, 'numeric');
            app.VDividerCurrentLabel = uilabel(app.ResistorsofOPAMPsPanel);
            app.VDividerCurrentLabel.Text = 'V-Divider (Current)';
            app.VDividerCurrentEditField = uieditfield(app.ResistorsofOPAMPsPanel, 'numeric');
            app.VDividerCurrentEditField.Value = 4;
            app.VDividerVoltageLabel = uilabel(app.ResistorsofOPAMPsPanel);
            app.VDividerVoltageLabel.Text = 'V-Divider (Voltage)';
            app.VDividerVoltageEditField = uieditfield(app.ResistorsofOPAMPsPanel, 'numeric');
            app.VDividerVoltageEditField.Value = 1;
            app.AddressesTab = uitab(app.TabGroup2);
            app.AddressesTab.Title = 'Addresses';
            app.ADS1115AddressEditFieldLabel = uilabel(app.AddressesTab);
            app.ADS1115AddressEditFieldLabel.Text = 'ADS1115 Address';
            app.ADS1115AddressEditField = uieditfield(app.AddressesTab, 'text');
            app.ADS1115AddressEditField.Value = '0x48';
            app.DACAddressEditFieldLabel = uilabel(app.AddressesTab);
            app.DACAddressEditFieldLabel.Text = 'DAC Address';
            app.DACAddressEditField = uieditfield(app.AddressesTab, 'text');
            app.DACAddressEditField.Value = '0x62';
            app.ThresholdsTab = uitab(app.TabGroup2);
            app.ThresholdsTab.Title = 'Thresholds';
            app.SecondaryVVEditFieldLabel = uilabel(app.ThresholdsTab);
            app.SecondaryVVEditFieldLabel.Text = 'Secondary V (V)';
            app.SecondaryVVEditField = uieditfield(app.ThresholdsTab, 'numeric');
            app.SecondaryVVEditField.Enable = 'off';
            app.SwitchtoSecondaryInAEditFieldLabel = uilabel(app.ThresholdsTab);
            app.SwitchtoSecondaryInAEditFieldLabel.Text = 'Switch to Secondary I(nA)';
            app.SwitchtoSecondaryInAEditField = uieditfield(app.ThresholdsTab, 'numeric');
            app.SwitchtoSecondaryInAEditField.Enable = 'off';
            app.SecondaryICutoffEditFieldLabel = uilabel(app.ThresholdsTab);
            app.SecondaryICutoffEditFieldLabel.Text = 'Secondary I Cutoff';
            app.SecondaryICutoffEditField = uieditfield(app.ThresholdsTab, 'numeric');
            app.SecondaryICutoffEditField.Enable = 'off';
            app.TroubleshootingTab = uitab(app.TabGroup2);
            app.TroubleshootingTab.Title = 'Troubleshooting';
            app.ExpectedValuesPanel = uipanel(app.TroubleshootingTab);
            app.ExpectedValuesPanel.Title = 'Expected Values';
            app.OPAMP2mVEditFieldLabel = uilabel(app.ExpectedValuesPanel);
            app.OPAMP2mVEditFieldLabel.Text = 'OP-AMP 2 (mV)';
            app.OPAMP2mVEditField = uieditfield(app.ExpectedValuesPanel, 'numeric');
            app.OPAMP2mVEditField.Editable = 'off';
            app.OPAMP3mVEditFieldLabel = uilabel(app.ExpectedValuesPanel);
            app.OPAMP3mVEditFieldLabel.Text = 'OP-AMP 3 (mV)';
            app.OPAMP3mVEditField = uieditfield(app.ExpectedValuesPanel, 'numeric');
            app.OPAMP3mVEditField.Editable = 'off';
            app.DACmVEditFieldLabel = uilabel(app.ExpectedValuesPanel);
            app.DACmVEditFieldLabel.Text = 'DAC (mV)';
            app.DACmVEditField = uieditfield(app.ExpectedValuesPanel, 'numeric');
            app.DACmVEditField.Editable = 'off';
            app.AppliedVVEditFieldLabel = uilabel(app.ExpectedValuesPanel);
            app.AppliedVVEditFieldLabel.Text = 'Applied V (V)';
            app.AppliedVVEditField = uieditfield(app.ExpectedValuesPanel, 'numeric');
            app.CalculateButton_2 = uibutton(app.ExpectedValuesPanel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.TestResistorMOhmEditFieldLabel = uilabel(app.ExpectedValuesPanel);
            app.TestResistorMOhmEditFieldLabel.Text = 'Test Resistor (MOhm)';
            app.TestResistorMOhmEditField = uieditfield(app.ExpectedValuesPanel, 'numeric');
            app.ADS1115A0A1mVEditFieldLabel = uilabel(app.ExpectedValuesPanel);
            app.ADS1115A0A1mVEditFieldLabel.Text = 'ADS1115 A0-A1 (mV)';
            app.ADS1115A0A1mVEditField = uieditfield(app.ExpectedValuesPanel, 'numeric');
            app.ADS1115A0A1mVEditField.Editable = 'off';
            app.ADS1115A2A3mVEditFieldLabel = uilabel(app.ExpectedValuesPanel);
            app.ADS1115A2A3mVEditFieldLabel.Text = 'ADS1115 A2-A3 (mV)';
            app.ADS1115A2A3mVEditField = uieditfield(app.ExpectedValuesPanel, 'numeric');
            app.ADS1115A2A3mVEditField.Editable = 'off';
            app.NanoporeVEditFieldLabel = uilabel(app.MainControlsTab_2);
            app.NanoporeVEditFieldLabel.Text = 'Nanopore (V)';
            app.NanoporeVEditField = uieditfield(app.MainControlsTab_2, 'numeric');
            app.CurrentnAEditFieldLabel = uilabel(app.MainControlsTab_2);
            app.CurrentnAEditFieldLabel.Text = 'Current (nA)';
            app.CurrentnAEditField = uieditfield(app.MainControlsTab_2, 'numeric');
            app.TabGroup3 = uitabgroup(app.MainControlsTab_2);
            app.CurrentTab = uitab(app.TabGroup3);
            app.CurrentTab.Title = 'Current';
            app.StdnAEditFieldLabel = uilabel(app.CurrentTab);
            app.StdnAEditFieldLabel.Text = 'Std (nA)';
            app.StdnAEditField = uieditfield(app.CurrentTab, 'numeric');
            app.RMSnAEditFieldLabel = uilabel(app.CurrentTab);
            app.RMSnAEditFieldLabel.Text = 'RMS (nA)';
            app.RMSnAEditField = uieditfield(app.CurrentTab, 'numeric');
            app.RMSVEditFieldLabel = uilabel(app.CurrentTab);
            app.RMSVEditFieldLabel.Text = 'RMS (V)';
            app.RMSVEditField = uieditfield(app.CurrentTab, 'numeric');
            app.StdmVEditFieldLabel = uilabel(app.CurrentTab);
            app.StdmVEditFieldLabel.Text = 'Std (mV)';
            app.StdmVEditField = uieditfield(app.CurrentTab, 'numeric');
            app.UIAxes = uiaxes(app.CurrentTab);
            title(app.UIAxes, 'Current vs Time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Current (nA)')
            zlabel(app.UIAxes, 'Z')
            app.GnSEditFieldLabel = uilabel(app.MainControlsTab_2);
            app.GnSEditFieldLabel.Text = 'G (nS)';
            app.GnSEditField = uieditfield(app.MainControlsTab_2, 'numeric');
            app.GnSEditField.Editable = 'off';
            app.RMohmEditFieldLabel = uilabel(app.MainControlsTab_2);
            app.RMohmEditFieldLabel.Text = 'R (Mohm)';
            app.RMohmEditField = uieditfield(app.MainControlsTab_2, 'numeric');
            app.RMohmEditField.Editable = 'off';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HelpTextAreaLabel = uilabel(app.HelpTab);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.HelpTab);
            app.HelpTextArea.Value = {'FBV:  First Bit Value'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARDUINO_CBD_PCB_DT_MOD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end